classdef PsApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ResultsPanel                    matlab.ui.container.Panel
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        LabelShortTl                    matlab.ui.control.Label
        LabelCap                        matlab.ui.control.Label
        SendingendpowerfactorEditField  matlab.ui.control.NumericEditField
        SendingendpowerfactorEditFieldLabel  matlab.ui.control.Label
        ALabel                          matlab.ui.control.Label
        SendingendcurrentEditField      matlab.ui.control.NumericEditField
        SendingendcurrentEditFieldLabel  matlab.ui.control.Label
        KVLabel_2                       matlab.ui.control.Label
        SendingendvoltageEditField      matlab.ui.control.NumericEditField
        SendingendvoltageEditFieldLabel  matlab.ui.control.Label
        MVALabel_2                      matlab.ui.control.Label
        SendingendpowerEditField        matlab.ui.control.NumericEditField
        SendingendpowerEditFieldLabel   matlab.ui.control.Label
        SendingendLabel                 matlab.ui.control.Label
        VoltageregulationEditField      matlab.ui.control.NumericEditField
        VoltageregulationEditFieldLabel  matlab.ui.control.Label
        EfficiencyEditField             matlab.ui.control.NumericEditField
        EfficiencyEditFieldLabel        matlab.ui.control.Label
        TLperformanceLabel              matlab.ui.control.Label
        nFLabel                         matlab.ui.control.Label
        CEditField                      matlab.ui.control.NumericEditField
        CLabel_2                        matlab.ui.control.Label
        mHLabel                         matlab.ui.control.Label
        LEditField                      matlab.ui.control.NumericEditField
        LEditFieldLabel                 matlab.ui.control.Label
        ohmLabel                        matlab.ui.control.Label
        REditField                      matlab.ui.control.NumericEditField
        REditFieldLabel                 matlab.ui.control.Label
        TLparametersperphaseLabel       matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        LoadPanel                       matlab.ui.container.Panel
        HzLabel                         matlab.ui.control.Label
        FrequencyEditField              matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel         matlab.ui.control.Label
        RecievingendpowerfactorEditField  matlab.ui.control.NumericEditField
        RecievingendpowerfactorEditFieldLabel  matlab.ui.control.Label
        KVLabel                         matlab.ui.control.Label
        RecievingendvoltageEditField    matlab.ui.control.NumericEditField
        RecievingendvoltageEditFieldLabel  matlab.ui.control.Label
        MVALabel                        matlab.ui.control.Label
        RecievingendpowerEditField      matlab.ui.control.NumericEditField
        RecievingendpowerEditFieldLabel  matlab.ui.control.Label
        TransmissionlinePanel           matlab.ui.container.Panel
        TLModelDropDown                 matlab.ui.control.DropDown
        TLModelDropDownLabel            matlab.ui.control.Label
        labelxdLabel                    matlab.ui.control.Label
        mLabel_5                        matlab.ui.control.Label
        mLabel_4                        matlab.ui.control.Label
        PhasecandaspacingEditField      matlab.ui.control.NumericEditField
        PhasecandaspacingEditFieldLabel  matlab.ui.control.Label
        PhasebandcspacingEditField      matlab.ui.control.NumericEditField
        PhasebandcspacingEditFieldLabel  matlab.ui.control.Label
        mLabel_3                        matlab.ui.control.Label
        PhaseaandbspacingEditField      matlab.ui.control.NumericEditField
        PhaseaandbspacingEditFieldLabel  matlab.ui.control.Label
        CLabel                          matlab.ui.control.Label
        AmbienttemperatureEditField     matlab.ui.control.NumericEditField
        AmbienttemperatureEditFieldLabel  matlab.ui.control.Label
        mLabel_2                        matlab.ui.control.Label
        BundlesspacingEditField         matlab.ui.control.NumericEditField
        BundlesspacingEditFieldLabel    matlab.ui.control.Label
        BundlesDropDown                 matlab.ui.control.DropDown
        BundlesDropDownLabel            matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        StranddiameterEditField         matlab.ui.control.NumericEditField
        StranddiameterEditFieldLabel    matlab.ui.control.Label
        NumberofstrandsEditField        matlab.ui.control.NumericEditField
        NumberofstrandsEditFieldLabel   matlab.ui.control.Label
        TLlengthEditField               matlab.ui.control.NumericEditField
        TLlengthEditFieldLabel          matlab.ui.control.Label
        KmLabel                         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
          % Inputs
            l = app.TLlengthEditField.Value ;
            strand_dia = app.StranddiameterEditField.Value ;
            strands = app.NumberofstrandsEditField.Value ;
            cond_dia = sqrt(((pi*strand_dia^2)/4)*strands*4/pi);
            bundles = app.BundlesDropDown.Value ;
            n=1;
            switch(bundles)
                case 'No bundles'
                    n =1;
                case '2 bundles'
                    n=2;
                case '3 bundles'
                    n=3;
                case '4 bundles'
                    n=4;
            end
            T = app.AmbienttemperatureEditField.Value ;
            ab = app.PhaseaandbspacingEditField.Value;
            bc = app.PhasebandcspacingEditField.Value;
            ca = app.PhasecandaspacingEditField.Value;
            bund_spacing = app.BundlesspacingEditField.Value;
            
            
            Sr = (app.RecievingendpowerEditField.Value *10^6 )/3;
            Vr = (app.RecievingendvoltageEditField.Value *1000)/sqrt(3);
            f = app.FrequencyEditField.Value;
            pf = app.RecievingendpowerfactorEditField.Value;
            
            %Resistance calculation:
            R = ((((2.825*10^-8 *(228+T)/248)*l*1000)/(((pi/4)*strand_dia^2)*strands))/n )*1.04 ;
            app.REditField.Value = R;
            
            %Inductance and capacitance calculation:
            GMD = (ab*bc*ca)^(1/3);
            GMRL =cond_dia*0.7788/2;
             GMRC = cond_dia/2;
            switch( bundles) 
                
                case '2 bundles'
                    GMRL = sqrt((cond_dia*0.7788/2)*bund_spacing);
                    GMRC = sqrt((cond_dia/2)*bund_spacing);

                case '3 bundles'
                    GMRL = ((cond_dia*0.7788/2)*bund_spacing^2)^(1/3);
                    GMRC = ((cond_dia/2)*bund_spacing^2)^(1/3);
              
                case '4 bundles'
                GMRL = 1.09 * ((cond_dia*0.7788/2)*bund_spacing^3)^(1/4);
                GMRC = 1.09 * ((cond_dia/2)*bund_spacing^3)^(1/4);
             
            end
            L = 2*10^-7 * log(GMD/GMRL) * l*1000;
            Cap = ((2*pi*8.85*10^-12)/(log(GMD/GMRC)))*l*1000;
            app.LEditField.Value = L *10^3;
            app.CEditField.Value = Cap *10^9;
            
            %Sending end calculations:
            w = 2*pi*f;
            y = complex(0,w*Cap);
            z =complex(R,w*L);
            Ir = conj((complex(Sr*pf,sqrt((Sr^2)-(Sr*pf)^2)))/complex(Vr,0));
            if l<=80 
                app.labelxdLabel.Text = 'Short T.L';
                app.CEditField.Visible = 0;
                app.nFLabel.Visible =0;
                app.LabelCap.Visible=1;
                app.LabelCap.Text ='Capacitance is neglected';
                app.CLabel_2.Visible=0;
                app.LabelShortTl.Visible=1;
                app.LabelShortTl.Text='(Short T.L)';
                A =1;
                B=complex(R,w*L);
                C =0;
            elseif l>80
                switch(app.TLModelDropDown.Value)
                    case 'PI-Model'
                        A = 1 + (z*y)/2;
                        B = z;
                        C =y*(1+(z*y/4));
                        
                    case 'T-Model'
                        A = 1+ (z*y)/2 ;
                        B = z*(1+(z*y/4));
                        C =y;
                end
                
            end
            Vs =A*Vr + B*Ir;
            Is = C*Vr + A*Ir ;
            Ssending = Vs*conj(Is);
            Ps = real(Ssending);
            pfsending= Ps/abs(Ssending);
            Efficiency = (Sr*pf/Ps)*100;
            VR = ((abs(Vs)-Vr)/Vr)*100;
            app.SendingendpowerEditField.Value = abs(Ssending)*3*10^-6;
            app.SendingendvoltageEditField.Value = abs(Vs)*sqrt(3)*10^-3;
            app.SendingendcurrentEditField.Value = abs(Is);
            app.SendingendpowerfactorEditField.Value = pfsending;
            app.EfficiencyEditField.Value = Efficiency;
            app.VoltageregulationEditField.Value = VR;
            
        end

        % Value changed function: TLlengthEditField
        function TLlengthEditFieldValueChanged(app, event)
            value = app.TLlengthEditField.Value;
            if value >80
                app.TLModelDropDown.Visible=1;
            elseif value <80
                 app.TLModelDropDown.Visible=0;
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TransmissionlinePanel = uipanel(app.UIFigure);
            app.TransmissionlinePanel.Title = '                                     Transmission line';
            app.KmLabel = uilabel(app.TransmissionlinePanel);
            app.KmLabel.Text = 'Km';
            app.TLlengthEditFieldLabel = uilabel(app.TransmissionlinePanel);
            app.TLlengthEditFieldLabel.Text = 'T.L length';
            app.TLlengthEditField = uieditfield(app.TransmissionlinePanel, 'numeric');
            app.TLlengthEditField.Limits = [0 240];
            app.TLlengthEditField.ValueChangedFcn = createCallbackFcn(app, @TLlengthEditFieldValueChanged, true);
            app.NumberofstrandsEditFieldLabel = uilabel(app.TransmissionlinePanel);
            app.NumberofstrandsEditFieldLabel.Text = 'Number of strands';
            app.NumberofstrandsEditField = uieditfield(app.TransmissionlinePanel, 'numeric');
            app.NumberofstrandsEditField.Limits = [1 Inf];
            app.NumberofstrandsEditField.Value = 1;
            app.StranddiameterEditFieldLabel = uilabel(app.TransmissionlinePanel);
            app.StranddiameterEditFieldLabel.Text = 'Strand diameter';
            app.StranddiameterEditField = uieditfield(app.TransmissionlinePanel, 'numeric');
            app.StranddiameterEditField.Limits = [0 Inf];
            app.mLabel = uilabel(app.TransmissionlinePanel);
            app.mLabel.Text = 'm';
            app.BundlesDropDownLabel = uilabel(app.TransmissionlinePanel);
            app.BundlesDropDownLabel.Text = 'Bundles';
            app.BundlesDropDown = uidropdown(app.TransmissionlinePanel);
            app.BundlesDropDown.Items = {'No bundles  ', '2 bundles', '3 bundles', '4 bundles'};
            app.BundlesDropDown.Value = 'No bundles  ';
            app.BundlesspacingEditFieldLabel = uilabel(app.TransmissionlinePanel);
            app.BundlesspacingEditFieldLabel.Text = 'Bundles spacing';
            app.BundlesspacingEditField = uieditfield(app.TransmissionlinePanel, 'numeric');
            app.BundlesspacingEditField.Limits = [0 Inf];
            app.mLabel_2 = uilabel(app.TransmissionlinePanel);
            app.mLabel_2.Text = 'm';
            app.AmbienttemperatureEditFieldLabel = uilabel(app.TransmissionlinePanel);
            app.AmbienttemperatureEditFieldLabel.Text = 'Ambient temperature';
            app.AmbienttemperatureEditField = uieditfield(app.TransmissionlinePanel, 'numeric');
            app.CLabel = uilabel(app.TransmissionlinePanel);
            app.CLabel.Text = 'C';
            app.PhaseaandbspacingEditFieldLabel = uilabel(app.TransmissionlinePanel);
            app.PhaseaandbspacingEditFieldLabel.Text = 'Phase a and b spacing';
            app.PhaseaandbspacingEditField = uieditfield(app.TransmissionlinePanel, 'numeric');
            app.PhaseaandbspacingEditField.Limits = [0 Inf];
            app.mLabel_3 = uilabel(app.TransmissionlinePanel);
            app.mLabel_3.Text = 'm';
            app.PhasebandcspacingEditFieldLabel = uilabel(app.TransmissionlinePanel);
            app.PhasebandcspacingEditFieldLabel.Text = 'Phase b and c spacing';
            app.PhasebandcspacingEditField = uieditfield(app.TransmissionlinePanel, 'numeric');
            app.PhasebandcspacingEditField.Limits = [0 Inf];
            app.PhasecandaspacingEditFieldLabel = uilabel(app.TransmissionlinePanel);
            app.PhasecandaspacingEditFieldLabel.Text = 'Phase c and a spacing';
            app.PhasecandaspacingEditField = uieditfield(app.TransmissionlinePanel, 'numeric');
            app.PhasecandaspacingEditField.Limits = [0 Inf];
            app.mLabel_4 = uilabel(app.TransmissionlinePanel);
            app.mLabel_4.Text = 'm';
            app.mLabel_5 = uilabel(app.TransmissionlinePanel);
            app.mLabel_5.Text = 'm';
            app.labelxdLabel = uilabel(app.TransmissionlinePanel);
            app.labelxdLabel.Text = 'labelxd';
            app.TLModelDropDownLabel = uilabel(app.TransmissionlinePanel);
            app.TLModelDropDownLabel.Visible = 'off';
            app.TLModelDropDownLabel.Text = 'T.L Model';
            app.TLModelDropDown = uidropdown(app.TransmissionlinePanel);
            app.TLModelDropDown.Items = {'PI-Model', 'T-Model'};
            app.TLModelDropDown.Visible = 'off';
            app.TLModelDropDown.Value = 'PI-Model';
            app.LoadPanel = uipanel(app.UIFigure);
            app.LoadPanel.Title = '                       Load';
            app.RecievingendpowerEditFieldLabel = uilabel(app.LoadPanel);
            app.RecievingendpowerEditFieldLabel.Text = 'Recieving end power';
            app.RecievingendpowerEditField = uieditfield(app.LoadPanel, 'numeric');
            app.RecievingendpowerEditField.Limits = [0 Inf];
            app.MVALabel = uilabel(app.LoadPanel);
            app.MVALabel.Text = 'MVA';
            app.RecievingendvoltageEditFieldLabel = uilabel(app.LoadPanel);
            app.RecievingendvoltageEditFieldLabel.Text = 'Recieving end voltage';
            app.RecievingendvoltageEditField = uieditfield(app.LoadPanel, 'numeric');
            app.RecievingendvoltageEditField.Limits = [0 Inf];
            app.KVLabel = uilabel(app.LoadPanel);
            app.KVLabel.Text = 'KV';
            app.RecievingendpowerfactorEditFieldLabel = uilabel(app.LoadPanel);
            app.RecievingendpowerfactorEditFieldLabel.Text = 'Recieving end power factor';
            app.RecievingendpowerfactorEditField = uieditfield(app.LoadPanel, 'numeric');
            app.RecievingendpowerfactorEditField.Limits = [0.01 0.99];
            app.RecievingendpowerfactorEditField.Value = 0.01;
            app.FrequencyEditFieldLabel = uilabel(app.LoadPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.LoadPanel, 'numeric');
            app.FrequencyEditField.Limits = [0 Inf];
            app.HzLabel = uilabel(app.LoadPanel);
            app.HzLabel.Text = 'Hz';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ResultsPanel = uipanel(app.UIFigure);
            app.ResultsPanel.Title = '                                                                                  Results';
            app.TLparametersperphaseLabel = uilabel(app.ResultsPanel);
            app.TLparametersperphaseLabel.Text = 'T.L parameters per phase';
            app.REditFieldLabel = uilabel(app.ResultsPanel);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.ResultsPanel, 'numeric');
            app.REditField.Limits = [0 Inf];
            app.ohmLabel = uilabel(app.ResultsPanel);
            app.ohmLabel.Text = 'ohm';
            app.LEditFieldLabel = uilabel(app.ResultsPanel);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.mHLabel = uilabel(app.ResultsPanel);
            app.mHLabel.Text = 'mH';
            app.CLabel_2 = uilabel(app.ResultsPanel);
            app.CLabel_2.Text = 'C';
            app.CEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.nFLabel = uilabel(app.ResultsPanel);
            app.nFLabel.Text = 'nF';
            app.TLperformanceLabel = uilabel(app.ResultsPanel);
            app.TLperformanceLabel.Text = 'T.L performance';
            app.EfficiencyEditFieldLabel = uilabel(app.ResultsPanel);
            app.EfficiencyEditFieldLabel.Text = 'Efficiency';
            app.EfficiencyEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.VoltageregulationEditFieldLabel = uilabel(app.ResultsPanel);
            app.VoltageregulationEditFieldLabel.Text = 'Voltage regulation';
            app.VoltageregulationEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.SendingendLabel = uilabel(app.ResultsPanel);
            app.SendingendLabel.Text = 'Sending end';
            app.SendingendpowerEditFieldLabel = uilabel(app.ResultsPanel);
            app.SendingendpowerEditFieldLabel.Text = 'Sending end power';
            app.SendingendpowerEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.MVALabel_2 = uilabel(app.ResultsPanel);
            app.MVALabel_2.Text = 'MVA';
            app.SendingendvoltageEditFieldLabel = uilabel(app.ResultsPanel);
            app.SendingendvoltageEditFieldLabel.Text = 'Sending end voltage';
            app.SendingendvoltageEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.KVLabel_2 = uilabel(app.ResultsPanel);
            app.KVLabel_2.Text = 'KV';
            app.SendingendcurrentEditFieldLabel = uilabel(app.ResultsPanel);
            app.SendingendcurrentEditFieldLabel.Text = 'Sending end current';
            app.SendingendcurrentEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.ALabel = uilabel(app.ResultsPanel);
            app.ALabel.Text = 'A';
            app.SendingendpowerfactorEditFieldLabel = uilabel(app.ResultsPanel);
            app.SendingendpowerfactorEditFieldLabel.Text = 'Sending end power factor';
            app.SendingendpowerfactorEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.LabelCap = uilabel(app.ResultsPanel);
            app.LabelCap.Visible = 'off';
            app.LabelCap.Text = 'LabelCap';
            app.LabelShortTl = uilabel(app.ResultsPanel);
            app.LabelShortTl.Visible = 'off';
            app.LabelShortTl.Text = 'LabelShortTl';
            app.Label = uilabel(app.ResultsPanel);
            app.Label.Text = '%';
            app.Label_2 = uilabel(app.ResultsPanel);
            app.Label_2.Text = '%';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PsApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end